classdef final8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SynthesizerPanel           matlab.ui.container.Panel
        DurationEditField          matlab.ui.control.NumericEditField
        DurationEditFieldLabel     matlab.ui.control.Label
        AmplitudeEditField         matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        DifferentWavesButtonGroup  matlab.ui.container.ButtonGroup
        AMWaveButton               matlab.ui.control.RadioButton
        SawtoothWaveButton         matlab.ui.control.RadioButton
        SquareWaveButton           matlab.ui.control.RadioButton
        SineWaveButton             matlab.ui.control.RadioButton
        SampleNoDropDown_3         matlab.ui.control.DropDown
        SampleNoDropDown_3Label    matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        ChoppingPanel              matlab.ui.container.Panel
        EndSlider                  matlab.ui.control.Slider
        EndSliderLabel             matlab.ui.control.Label
        StartSlider                matlab.ui.control.Slider
        StartSliderLabel           matlab.ui.control.Label
        SampleNoDropDown_2         matlab.ui.control.DropDown
        SampleNoDropDown_2Label    matlab.ui.control.Label
        ApplyToSampleButton        matlab.ui.control.Button
        EffectsandSampleModificationPanel  matlab.ui.container.Panel
        SampleNoDropDown           matlab.ui.control.DropDown
        SampleNoDropDownLabel      matlab.ui.control.Label
        ExportNewAudioButton       matlab.ui.control.Button
        ClippingSlider             matlab.ui.control.Slider
        ClippingLabel              matlab.ui.control.Label
        TremoloRateSlider          matlab.ui.control.Slider
        TremoloRateLabel           matlab.ui.control.Label
        TremoloDepthSlider         matlab.ui.control.Slider
        TremoloDepthLabel          matlab.ui.control.Label
        kHzSlider_2                matlab.ui.control.Slider
        kHzSlider_2Label           matlab.ui.control.Label
        kHzSlider                  matlab.ui.control.Slider
        kHzSliderLabel             matlab.ui.control.Label
        HzSlider_4                 matlab.ui.control.Slider
        HzLabel_3                  matlab.ui.control.Label
        HzSlider_3                 matlab.ui.control.Slider
        HzLabel_2                  matlab.ui.control.Label
        HzSlider_2                 matlab.ui.control.Slider
        HzLabel                    matlab.ui.control.Label
        HzSlider                   matlab.ui.control.Slider
        HzSliderLabel              matlab.ui.control.Label
        EqualizerLabel             matlab.ui.control.Label
        DelayTimeKnob              matlab.ui.control.Knob
        DelayTimeKnobLabel         matlab.ui.control.Label
        FeedbackLevelKnob          matlab.ui.control.Knob
        FeedbackLevelKnobLabel     matlab.ui.control.Label
        DelayLabel                 matlab.ui.control.Label
        PlaybackDirectionLabel     matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        VolumeLevelKnob            matlab.ui.control.Knob
        VolumeLevelKnobLabel       matlab.ui.control.Label
        VolumeLabel                matlab.ui.control.Label
        PlaybackSpeedLabel         matlab.ui.control.Label
        PlaybackSppedSlider        matlab.ui.control.Slider
        TextArea                   matlab.ui.control.TextArea
        SampleSelectionPanel       matlab.ui.container.Panel
        LoadButton_9               matlab.ui.control.Button
        Sample9Button              matlab.ui.control.Button
        LoadButton_8               matlab.ui.control.Button
        Sample8Button              matlab.ui.control.Button
        LoadButton_7               matlab.ui.control.Button
        Sample7Button              matlab.ui.control.Button
        LoadButton_6               matlab.ui.control.Button
        Sample6Button              matlab.ui.control.Button
        LoadButton_5               matlab.ui.control.Button
        Sample5Button              matlab.ui.control.Button
        LoadButton_4               matlab.ui.control.Button
        Sample4Button              matlab.ui.control.Button
        LoadButton_3               matlab.ui.control.Button
        Sample3Button              matlab.ui.control.Button
        LoadButton_2               matlab.ui.control.Button
        Sample2Button              matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        Sample1Button              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %With Sample and load bottons
        SampleData_Cell = cell(3,3)
        Samplefs = cell(3,3)
        CurrentPlayer = []
        %Determine that there is a sample playing or not
        Isplaying = false

        SynthSignal double = []  
        % Default sampling frequency
        Fs double = 44100


        DelayEffect
        EqualizerFilter

        TremoloRate double = 5 %Frequency,Hz
        TremoloDepth double = 0.5%depth

        ClippingThreshold double = 0.8 %clip threshold
        

    end         
    
    methods (Access = private)
        function [row, col, sampleIndex] = getSampleIndex(app, dropdownValue)
            parts = strsplit(dropdownValue, ' ');
            sampleIndex = str2double(parts{2});
            row = ceil(sampleIndex / 3);
            col = mod(sampleIndex - 1, 3) + 1;
        end
        
        %Exit the function if there is no sample in here
        function playAudioSample(app, row, col)
            sampleIndex = (row-1)*3 + col;
            
            if isempty(app.SampleData_Cell{row, col})
                app.TextArea.Value = sprintf('No audio loaded in Sample %d', sampleIndex);
                return;
            end
            
            %I was wondering how to stop that
            %This part assist by AI
            if ~isempty(app.CurrentPlayer) && isplaying(app.CurrentPlayer)
                stop(app.CurrentPlayer);
                app.Isplaying = false;
            end
            
            %Get the audio data and newst sampling rate
            audioData = app.SampleData_Cell{row, col};
            fs = app.Samplefs{row, col};
            
            audioToPlay = app.applyModification(audioData, fs);
            
            try
                speed = app.PlaybackSppedSlider.Value;
                app.CurrentPlayer = audioplayer(audioToPlay, fs * speed);%update speed
                
                play(app.CurrentPlayer);
                app.Isplaying = true;
                
                buttonName = ['Sample', num2str(sampleIndex), 'Button'];
                titleStr = sprintf('Playing: %s', app.(buttonName).Text);
                
                app.updateWaveformDisplay(audioData, fs, titleStr); 
                app.TextArea.Value = titleStr;
                
            catch ME
                %If there is somthing wrong with the sample
                errorMsg = sprintf('Error playing sample %d: %s', sampleIndex, ME.message);
                app.TextArea.Value = errorMsg;
            end
        end
        
        function updateWaveformDisplay(app, audioData, fs, titleStr)
            cla(app.UIAxes);
            
            t = (0:length(audioData)-1) / fs;
            
            plot(app.UIAxes, t, audioData, 'b', 'LineWidth', 1);
            
            %Make the plot
            title(app.UIAxes, titleStr);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            grid(app.UIAxes, 'on');
            
            if ~isempty(t)
                xlim(app.UIAxes, [0, t(end)]);
            end
            
            drawnow;
        end
        
        function loadAudioPlugin(app, row, col)
            %Different kinds of files to put in
            [file, path] = uigetfile({'*.wav;*.mp3;*.m4a;*.flac', 'Audio Files (*.wav, *.mp3, *.m4a, *.flac)'});
            if isequal(file, 0)
                return; 
            end
            
            fullPath = fullfile(path, file);
            try
                [y, fs] = audioread(fullPath);
                if size(y, 2) > 1
                    y = mean(y, 2); 
                end
                
                %Store data
                app.SampleData_Cell{row, col} = y;
                app.Samplefs{row, col} = fs;
                
                sampleIndex = (row-1)*3 + col;

                %updatw the ui botton
                buttonName = ['Sample', num2str(sampleIndex), 'Button'];
                app.(buttonName).Text = file;
                
                app.TextArea.Value = sprintf('Loaded %s into Sample %d', file, sampleIndex);

                [r_chop, c_chop, ~] = app.getSampleIndex(app.SampleNoDropDown_2.Value);
                if r_chop == row && c_chop == col
                    app.updateChoppingSliders(row, col);
                end
                
            catch ME
                %Something wrong with the loading
                app.TextArea.Value = sprintf('Error loading file: %s', ME.message);
            end
        end

        function modifiedAudio = applyModification(app, audioData, fs)
            %volume
            gain = app.VolumeLevelKnob.Value / 50;%get knob value
            modifiedAudio = audioData * gain;%apply
            
            %playback
             if strcmp(app.Switch.Value, 'Backward')%compare string
                 modifiedAudio = flipud(modifiedAudio);%flip up and down
             end


            %clipping
            threshold = app.ClippingThreshold;
            modifiedAudio = max(-threshold, min(threshold, modifiedAudio));

            %tremolo
            N = length(modifiedAudio);%generate signal
            t = (0:N-1)' / fs;
            
            modulator = sin(2 * pi * app.TremoloRate * t);%formula of tremolo helped by gemini
            modulationIndex = 1 + app.TremoloDepth * modulator;
            
            modifiedAudio = modifiedAudio .* modulationIndex;

            %equalizer
            centerFreqs = [63, 125, 250, 500, 1000, 2000];
            gains_dB = [app.HzSlider.Value, app.HzSlider_2.Value, app.HzSlider_3.Value, app.HzSlider_4.Value, app.kHzSlider.Value, app.kHzSlider_2.Value];
  
            gains_linear = 10 .^ (gains_dB / 20); %dB gain to linear gain
            
            eqOutput = modifiedAudio;
            
            for i = 1:length(centerFreqs)
                if abs(gains_linear(i) - 1) > 0.01
                    f0 = centerFreqs(i);
                    Q = 2; % fixed Q value for simplicity, large Q, larger effect
                    
                    %assisted by gemini, on formula and function
                    %cutoff frequencies
                    lowCut = f0 / (2^(1/(2*Q)));
                    highCut = f0 * (2^(1/(2*Q)));
                    
                    % Low pass: filters out high frequencies above highCut
                    [blp, alp] = butter(1, highCut/(fs/2), 'low');
                    % High pass: filters out low frequencies below lowCut
                    [bhp, ahp] = butter(1, lowCut/(fs/2), 'high');
                    
                    % Cascade filters to get the band-pass signal
                    bandSignal = filter(blp, alp, modifiedAudio);
                    bandSignal = filter(bhp, ahp, bandSignal);
                    
                    eqOutput = eqOutput - bandSignal + (bandSignal * gains_linear(i));
                end
            end
            
            modifiedAudio = eqOutput;
            
            %delay
            delayTime = app.DelayTimeKnob.Value; 
            feedback = app.FeedbackLevelKnob.Value;
            
            if delayTime > 0 && feedback > 0
                delaySamples = round(delayTime * fs); % convert to samples
                if delaySamples < length(modifiedAudio)
                    % create delayed signal, pre-pad with zeros
                    delayedSignal = [zeros(delaySamples, 1); modifiedAudio(1:end-delaySamples)];
                    % apply feedback gain, mix
                    modifiedAudio = modifiedAudio + delayedSignal * (feedback / 10);
                end
            end
            
            %normalization, ensure signal stays within [-1, 1] range, so
            %they won't clip and prevent distortion
            maxAmp = max(abs(modifiedAudio));
            if maxAmp > 1
                modifiedAudio = modifiedAudio / maxAmp;
            end
        end

        %chopp
        function updateChoppingSliders(app, row, col)
            if isempty(app.SampleData_Cell{row, col})%if empty/no sample, dont allow chop

                app.StartSlider.Limits = [0, 1];
                app.StartSlider.Value = 0;
                app.EndSlider.Limits = [0, 1];
                app.EndSlider.Value = 1;
                app.StartSlider.Enable = 'off';
                app.EndSlider.Enable = 'off';

                return;
            end

            audioData = app.SampleData_Cell{row, col};%check duaration
            fs = app.Samplefs{row, col};
            duration = length(audioData) / fs;
            
            app.StartSlider.Limits = [0, duration];%update sample duration to slider
            app.StartSlider.Value = 0;
            app.EndSlider.Limits = [0, duration];
            app.EndSlider.Value = duration;
            
            app.StartSlider.Enable = 'on';%open to chop
            app.EndSlider.Enable = 'on';
            
            app.TextArea.Value = sprintf('Chopping range set: 0 to %.2f s', duration);%feedback
        end

%reach goal: export new audio
        function saveCurrentSample(app, row, col)
            sampleIndex = (row-1)*3 + col;
            
            if isempty(app.SampleData_Cell{row, col})
                app.TextArea.Value = sprintf('Cannot export: Sample %d is empty.', sampleIndex);%display:empty
                return;
            end
            
            [file, path] = uiputfile('*.wav', 'Save Audio File', sprintf('Sample%d.wav', sampleIndex));%file path/name
            if isequal(file, 0)
                return; 
            end
            
            fullPath = fullfile(path, file);
            
            try
                audioData = app.SampleData_Cell{row, col};
                fs = app.Samplefs{row, col};
                
                [r_chop, c_chop, ~] = app.getSampleIndex(app.SampleNoDropDown_2.Value);%check chopping,crop array before apply effects
                if r_chop == row && c_chop == col
                    start_time = app.StartSlider.Value;
                    end_time = app.EndSlider.Value;
                    
                    start_sample = max(1, round(start_time * fs) + 1);
                    end_sample = min(length(audioData), round(end_time * fs));
                    
                    audioData = audioData(start_sample:end_sample);
                end
                
                audioToSave = app.applyModification(audioData, fs);%call main function, mix effects into audio
                
                audiowrite(fullPath, audioToSave, fs);%save
                
                app.TextArea.Value = sprintf('Successfully exported Sample %d to %s', sampleIndex, file);%display: success/fail
            catch ME
                app.TextArea.Value = sprintf('Error exporting file: %s', ME.message);
            end

        end
        function EffectKnobOrSliderChanged(app, ~)%display:effect applied
            app.TextArea.Value = 'Effect parameters updated. Click a Sample button to apply during playback.';
        end

        function generateSynthSignal(app)
            duration = app.DurationEditField.Value;                
            fs = app.Fs;                  
            t = (0:1/fs:duration)';       

            f = app.FrequencyEditField.Value;
            A = app.AmplitudeEditField.Value;
            fm = 4;                       
            m = 0.5;                     

            waveType = app.DifferentWavesButtonGroup.SelectedObject.Text;

            switch waveType
                case 'Sine Wave'
                    y = A * sin(2*pi*f*t);

                case 'Square Wave'
                    y = A * square(2*pi*f*t);

                case 'Sawtooth Wave'
                    y = A * sawtooth(2*pi*f*t);

                case 'AM Wave'
                    carrier = sin(2*pi*f*t);
                    modSig = 1 + m*sin(2*pi*fm*t);
                    y = A * modSig .* carrier;

            otherwise
                    y = zeros(size(t));
            end

            app.SynthSignal = y;
            app.plotSynthWaveform(t, y);
        end

        function plotSynthWaveform(app, t, y)
            cla(app.UIAxes2);

            plot(app.UIAxes2, t, y, 'LineWidth', 1);
            title(app.UIAxes2, 'Synthesized Soundwave');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude');
            grid(app.UIAxes2, 'on');

            if ~isempty(t)
                xlim(app.UIAxes2, [0, t(end)]);
            end

            drawnow;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = 'App initialized.';
            app.updateChoppingSliders(1, 1);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadAudioPlugin(app,1,1)
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            loadAudioPlugin(app,1,2)
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            loadAudioPlugin(app,1,3)
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            loadAudioPlugin(app,2,1)
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            loadAudioPlugin(app,2,2)
        end

        % Button pushed function: LoadButton_6
        function LoadButton_6Pushed(app, event)
            loadAudioPlugin(app,2,3)
        end

        % Button pushed function: LoadButton_7
        function LoadButton_7Pushed(app, event)
            loadAudioPlugin(app,3,1)
        end

        % Button pushed function: LoadButton_8
        function LoadButton_8Pushed(app, event)
            loadAudioPlugin(app,3,2)
        end

        % Button pushed function: LoadButton_9
        function LoadButton_9Pushed(app, event)
            loadAudioPlugin(app,3,3)
        end

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            playAudioSample(app, 1, 1);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            playAudioSample(app, 1, 2);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            playAudioSample(app, 1, 3);
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            playAudioSample(app, 2, 1);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            playAudioSample(app, 2, 2);
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            playAudioSample(app, 2, 3);
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            playAudioSample(app, 3, 1);
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            playAudioSample(app, 3, 2);
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            playAudioSample(app, 3, 3);
        end

        % Value changed function: SampleNoDropDown
        function SampleNoDropDownValueChanged(app, event)
            [row, col, sampleIndex] = app.getSampleIndex(app.SampleNoDropDown.Value);
            if ~isempty(app.SampleData_Cell{row, col})
                 buttonName = ['Sample', num2str(sampleIndex), 'Button'];
                 titleStr = sprintf('Selected for Effects: %s', app.(buttonName).Text);
                 app.updateWaveformDisplay(app.SampleData_Cell{row, col}, app.Samplefs{row, col}, titleStr);
            else
                 app.updateWaveformDisplay(zeros(app.Fs, 1), app.Fs, 'No Sample Loaded');
                 app.TextArea.Value = sprintf('%s selected, but audio is empty.', app.SampleNoDropDown.Value);
            end
            
        end

        % Value changed function: VolumeLevelKnob
        function VolumeLevelKnobValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: PlaybackSppedSlider
        function PlaybackSppedSliderValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: FeedbackLevelKnob
        function FeedbackLevelKnobValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Button pushed function: ExportNewAudioButton
        function ExportNewAudioButtonPushed(app, event)
            [row, col, ~] = app.getSampleIndex(app.SampleNoDropDown.Value);
            app.saveCurrentSample(row, col);
        end

        % Value changed function: SampleNoDropDown_2
        function SampleNoDropDown_2ValueChanged(app, event)
            [row, col, ~] = app.getSampleIndex(app.SampleNoDropDown_2.Value);
            app.updateChoppingSliders(row, col);
        end

        % Value changed function: StartSlider
        function StartSliderValueChanged(app, event)
            if app.StartSlider.Value > app.EndSlider.Value
                app.StartSlider.Value = app.EndSlider.Value;
                app.TextArea.Value = 'Start time cannot be greater than End time.';
            end
        end

        % Value changed function: EndSlider
        function EndSliderValueChanged(app, event)
            if app.EndSlider.Value < app.StartSlider.Value
                app.EndSlider.Value = app.StartSlider.Value;
                app.TextArea.Value = 'End time cannot be less than Start time.';
             end
        end

        % Button pushed function: ApplyToSampleButton
        function ApplyToSampleButtonPushed(app, event)
            [row, col, sampleIndex] = app.getSampleIndex(app.SampleNoDropDown_2.Value);
            
            if isempty(app.SampleData_Cell{row, col})
                app.TextArea.Value = sprintf('Cannot chop: Sample %d is empty.', sampleIndex);%display:empty
                return;
            end
            
            start_time = app.StartSlider.Value;%get start and end time from slider
            end_time = app.EndSlider.Value;
            
            audioData = app.SampleData_Cell{row, col};
            fs = app.Samplefs{row, col};
            
            start_sample = max(1, round(start_time * fs) + 1);%calculation
            end_sample = min(length(audioData), round(end_time * fs));
            
            if start_sample >= end_sample%ensure valid chop
                app.TextArea.Value = 'Invalid chop range. Start time is greater than or equal to End time.';
                return;
            end
            choppedAudio = audioData(start_sample:end_sample);%chop
            app.SampleData_Cell{row, col} = choppedAudio;%save
            
    
            buttonName = ['Sample', num2str(sampleIndex), 'Button'];
            titleStr = sprintf('Chopped and Saved: %s (%.2f-%.2f s)', app.(buttonName).Text, start_time, end_time);%display
            app.updateWaveformDisplay(choppedAudio, fs, titleStr);%update plot
            
            app.TextArea.Value = sprintf('Sample %d chopped and saved. New duration: %.2f s', sampleIndex, length(choppedAudio) / fs);
            
            app.updateChoppingSliders(row, col);%prepare for next chop
   
        end

        % Value changed function: DelayTimeKnob
        function DelayTimeKnobValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: HzSlider
        function HzSliderValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
            app.EffectKnobOrSliderChanged(event);
        end

        % Selection changed function: DifferentWavesButtonGroup
        function DifferentWavesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DifferentWavesButtonGroup.SelectedObject;
            app.generateSynthSignal();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.SynthSignal)
                app.TextArea.Value = 'No synthesized signal to save.';
                return;
            end

            [row, col, sampleIndex] = app.getSampleIndex(app.SampleNoDropDown_3.Value);

            app.SampleData_Cell{row, col} = app.SynthSignal;
            app.Samplefs{row, col} = app.Fs;

            buttonName = ['Sample', num2str(sampleIndex), 'Button'];
            waveName   = app.DifferentWavesButtonGroup.SelectedObject.Text;
            app.(buttonName).Text = ['Synth ', waveName];

            app.TextArea.Value = sprintf('Saved synthesized %s to Sample %d', waveName, sampleIndex);
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
            app.generateSynthSignal();
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            app.generateSynthSignal();
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.generateSynthSignal();
        end

        % Value changed function: TremoloRateSlider
        function TremoloRateSliderValueChanged(app, event)
            app.TremoloRate = app.TremoloRateSlider.Value;
            app.TextArea.Value = 'Tremolo Rate updated. Click a Sample button to apply during playback.';
        end

        % Value changed function: TremoloDepthSlider
        function TremoloDepthSliderValueChanged(app, event)
            app.TremoloDepth = app.TremoloDepthSlider.Value / 100; 
            app.TextArea.Value = 'Tremolo Depth updated. Click a Sample button to apply during playback.';
        end

        % Value changed function: ClippingSlider
        function ClippingSliderValueChanged(app, event)
            app.ClippingThreshold = app.ClippingSlider.Value;
            app.TextArea.Value = 'Clipping Threshold updated. Lower values cause more distortion.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Soundwave')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SampleSelectionPanel = uipanel(app.UIFigure);
            app.SampleSelectionPanel.Title = 'Sample Selection';
            app.Sample1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample 1';
            app.LoadButton = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Sample2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample 2';
            app.LoadButton_2 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.Sample3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample 3';
            app.LoadButton_3 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.Sample4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample 4';
            app.LoadButton_4 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.Sample5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample 5';
            app.LoadButton_5 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.Sample6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample 6';
            app.LoadButton_6 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_6Pushed, true);
            app.LoadButton_6.Text = 'Load';
            app.Sample7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample 7';
            app.LoadButton_7 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_7Pushed, true);
            app.LoadButton_7.Text = 'Load';
            app.Sample8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample 8';
            app.LoadButton_8 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_8Pushed, true);
            app.LoadButton_8.Text = 'Load';
            app.Sample9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample 9';
            app.LoadButton_9 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_9Pushed, true);
            app.LoadButton_9.Text = 'Load';
            app.TextArea = uitextarea(app.UIFigure);
            app.EffectsandSampleModificationPanel = uipanel(app.UIFigure);
            app.EffectsandSampleModificationPanel.Title = 'Effects and Sample Modification';
            app.PlaybackSppedSlider = uislider(app.EffectsandSampleModificationPanel);
            app.PlaybackSppedSlider.Limits = [0.25 2];
            app.PlaybackSppedSlider.ValueChangedFcn = createCallbackFcn(app, @PlaybackSppedSliderValueChanged, true);
            app.PlaybackSppedSlider.Value = 0.25;
            app.PlaybackSpeedLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackSpeedLabel.Text = ' Playback Speed';
            app.VolumeLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeLabel.Text = ' Volume';
            app.VolumeLevelKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeLevelKnobLabel.Text = 'Volume Level';
            app.VolumeLevelKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.VolumeLevelKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeLevelKnobValueChanged, true);
            app.Switch = uiswitch(app.EffectsandSampleModificationPanel, 'slider');
            app.Switch.Items = {'Forward', 'Backward'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Forward';
            app.PlaybackDirectionLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackDirectionLabel.Text = ' Playback Direction';
            app.DelayLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayLabel.Text = ' Delay';
            app.FeedbackLevelKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.FeedbackLevelKnobLabel.Text = 'Feedback Level';
            app.FeedbackLevelKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.FeedbackLevelKnob.Limits = [0 5];
            app.FeedbackLevelKnob.ValueChangedFcn = createCallbackFcn(app, @FeedbackLevelKnobValueChanged, true);
            app.DelayTimeKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayTimeKnobLabel.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.DelayTimeKnob.Limits = [0 2];
            app.DelayTimeKnob.ValueChangedFcn = createCallbackFcn(app, @DelayTimeKnobValueChanged, true);
            app.EqualizerLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.EqualizerLabel.Text = '      Equalizer';
            app.HzSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.HzSliderLabel.Text = '63 Hz';
            app.HzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider.Limits = [-10 10];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel.Text = '125 Hz';
            app.HzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_2.Limits = [-10 10];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzLabel_2 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_2.Text = '250 Hz';
            app.HzSlider_3 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_3.Limits = [-10 10];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.HzLabel_3 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_3.Text = '500 Hz';
            app.HzSlider_4 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_4.Limits = [-10 10];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.kHzSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSliderLabel.Text = '1k Hz';
            app.kHzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSlider.Limits = [-10 10];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzSlider_2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2Label.Text = '2k Hz';
            app.kHzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2.Limits = [-10 10];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.TremoloDepthLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.TremoloDepthLabel.Text = 'Tremolo Depth ';
            app.TremoloDepthSlider = uislider(app.EffectsandSampleModificationPanel);
            app.TremoloDepthSlider.ValueChangedFcn = createCallbackFcn(app, @TremoloDepthSliderValueChanged, true);
            app.TremoloRateLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.TremoloRateLabel.Text = 'Tremolo Rate ';
            app.TremoloRateSlider = uislider(app.EffectsandSampleModificationPanel);
            app.TremoloRateSlider.Limits = [0.5 15];
            app.TremoloRateSlider.ValueChangedFcn = createCallbackFcn(app, @TremoloRateSliderValueChanged, true);
            app.TremoloRateSlider.Value = 0.5;
            app.ClippingLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.ClippingLabel.Text = 'Clipping ';
            app.ClippingSlider = uislider(app.EffectsandSampleModificationPanel);
            app.ClippingSlider.Limits = [0.1 1];
            app.ClippingSlider.ValueChangedFcn = createCallbackFcn(app, @ClippingSliderValueChanged, true);
            app.ClippingSlider.Value = 0.1;
            app.ExportNewAudioButton = uibutton(app.EffectsandSampleModificationPanel, 'push');
            app.ExportNewAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNewAudioButtonPushed, true);
            app.ExportNewAudioButton.Text = 'Export New Audio';
            app.SampleNoDropDownLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDownLabel.Text = 'Sample No';
            app.SampleNoDropDown = uidropdown(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDown.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleNoDropDownValueChanged, true);
            app.SampleNoDropDown.Value = 'Sample 1';
            app.ChoppingPanel = uipanel(app.UIFigure);
            app.ChoppingPanel.Title = 'Chopping';
            app.ApplyToSampleButton = uibutton(app.ChoppingPanel, 'push');
            app.ApplyToSampleButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyToSampleButtonPushed, true);
            app.ApplyToSampleButton.Text = 'Apply To Sample';
            app.SampleNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SampleNoDropDown_2Label.Text = 'Sample No';
            app.SampleNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SampleNoDropDown_2.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SampleNoDropDown_2ValueChanged, true);
            app.SampleNoDropDown_2.Value = 'Sample 1';
            app.StartSliderLabel = uilabel(app.ChoppingPanel);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ChoppingPanel);
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @StartSliderValueChanged, true);
            app.EndSliderLabel = uilabel(app.ChoppingPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.ChoppingPanel);
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @EndSliderValueChanged, true);
            app.SynthesizerPanel = uipanel(app.UIFigure);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes2 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes2, 'Soundwave')
            xlabel(app.UIAxes2, 'Times')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SampleNoDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SampleNoDropDown_3Label.Text = 'Sample No';
            app.SampleNoDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SampleNoDropDown_3.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown_3.Value = 'Sample 1';
            app.DifferentWavesButtonGroup = uibuttongroup(app.SynthesizerPanel);
            app.DifferentWavesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifferentWavesButtonGroupSelectionChanged, true);
            app.DifferentWavesButtonGroup.Title = 'Different Waves';
            app.SineWaveButton = uiradiobutton(app.DifferentWavesButtonGroup);
            app.SineWaveButton.Text = 'Sine Wave';
            app.SineWaveButton.Value = true;
            app.SquareWaveButton = uiradiobutton(app.DifferentWavesButtonGroup);
            app.SquareWaveButton.Text = 'Square Wave';
            app.SawtoothWaveButton = uiradiobutton(app.DifferentWavesButtonGroup);
            app.SawtoothWaveButton.Text = 'Sawtooth Wave';
            app.AMWaveButton = uiradiobutton(app.DifferentWavesButtonGroup);
            app.AMWaveButton.Text = 'AM Wave';
            app.FrequencyEditFieldLabel = uilabel(app.SynthesizerPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SynthesizerPanel, 'numeric');
            app.FrequencyEditField.Limits = [1 30];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 5;
            app.AmplitudeEditFieldLabel = uilabel(app.SynthesizerPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SynthesizerPanel, 'numeric');
            app.AmplitudeEditField.Limits = [0 2];
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 1;
            app.DurationEditFieldLabel = uilabel(app.SynthesizerPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.SynthesizerPanel, 'numeric');
            app.DurationEditField.Limits = [0.1 5];
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.DurationEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end