classdef myfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SynthesizerPanel         matlab.ui.container.Panel
        ButtonGroup              matlab.ui.container.ButtonGroup
        AMWaveButton             matlab.ui.control.RadioButton
        SawtoothWaveButton       matlab.ui.control.RadioButton
        SquareWaveButton         matlab.ui.control.RadioButton
        SineWaveButton           matlab.ui.control.RadioButton
        SampleNoDropDown_3       matlab.ui.control.DropDown
        SampleNoDropDown_3Label  matlab.ui.control.Label
        SaveButton_3             matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        ChoppingPanel            matlab.ui.container.Panel
        EndSlider                matlab.ui.control.Slider
        EndSliderLabel           matlab.ui.control.Label
        StartSlider              matlab.ui.control.Slider
        StartSliderLabel         matlab.ui.control.Label
        SampleNoDropDown_2       matlab.ui.control.DropDown
        SampleNoDropDown_2Label  matlab.ui.control.Label
        SaveButton_2             matlab.ui.control.Button
        EffectsandSampleModificationPanel  matlab.ui.container.Panel
        kHzSlider_2              matlab.ui.control.Slider
        kHzSlider_2Label         matlab.ui.control.Label
        kHzSlider                matlab.ui.control.Slider
        kHzSliderLabel           matlab.ui.control.Label
        HzSlider_4               matlab.ui.control.Slider
        HzLabel_3                matlab.ui.control.Label
        HzSlider_3               matlab.ui.control.Slider
        HzLabel_2                matlab.ui.control.Label
        HzSlider_2               matlab.ui.control.Slider
        HzLabel                  matlab.ui.control.Label
        HzSlider                 matlab.ui.control.Slider
        HzSliderLabel            matlab.ui.control.Label
        EqualizerLabel           matlab.ui.control.Label
        DelayTimeKnob            matlab.ui.control.Knob
        DelayTimeKnobLabel       matlab.ui.control.Label
        FeedbackLevelKnob        matlab.ui.control.Knob
        FeedbackLevelKnobLabel   matlab.ui.control.Label
        DelayLabel               matlab.ui.control.Label
        PlaybackDirectionLabel   matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        VolumeLevelKnob          matlab.ui.control.Knob
        VolumeLevelKnobLabel     matlab.ui.control.Label
        VolumeLabel              matlab.ui.control.Label
        PlaybackSpeedLabel       matlab.ui.control.Label
        PlaybackSppedSlider      matlab.ui.control.Slider
        SaveButton               matlab.ui.control.Button
        SampleNoDropDown         matlab.ui.control.DropDown
        SampleNoDropDownLabel    matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        SampleSelectionPanel     matlab.ui.container.Panel
        LoadButton_9             matlab.ui.control.Button
        Sample9Button            matlab.ui.control.Button
        LoadButton_8             matlab.ui.control.Button
        Sample8Button            matlab.ui.control.Button
        LoadButton_7             matlab.ui.control.Button
        Sample7Button            matlab.ui.control.Button
        LoadButton_6             matlab.ui.control.Button
        Sample6Button            matlab.ui.control.Button
        LoadButton_5             matlab.ui.control.Button
        Sample5Button            matlab.ui.control.Button
        LoadButton_4             matlab.ui.control.Button
        Sample4Button            matlab.ui.control.Button
        LoadButton_3             matlab.ui.control.Button
        Sample3Button            matlab.ui.control.Button
        LoadButton_2             matlab.ui.control.Button
        Sample2Button            matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        Sample1Button            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SampleData_Cell = cell(3,3)
        Samplefs = cell(3,3)
        CurrentPlayer = []
        Isplaying = false

        SynthSignal double = [] 
        % Default sampling frequency
        Fs double = 44100
              
    end         
    
    methods (Access = private)
        
        function playAudioSample(app, row, col)
        if isempty(app.SampleData_Cell{row, col})
            sampleIndex = (row-1)*3 + col;
            app.TextArea.Value = sprintf('No audio loaded in Sample %d', sampleIndex);
            return;
        end
        
        if ~isempty(app.CurrentPlayer) && isplaying(app.CurrentPlayer)
            stop(app.CurrentPlayer);
            app.Isplaying = false;
        end
        
        audioData = app.SampleData_Cell{row, col};
        fs = app.Samplefs{row, col};
        
        try
            app.CurrentPlayer = audioplayer(audioData, fs);
            
            play(app.CurrentPlayer);
            app.Isplaying = true;
            
            sampleIndex = (row-1)*3 + col;
            buttonName = ['Sample', num2str(sampleIndex), 'Button'];
            titleStr = sprintf('Playing: %s', app.(buttonName).Text);
            
            updateWaveformDisplay(app, audioData, fs, titleStr);
            app.TextArea.Value = titleStr;
            
        catch ME
            sampleIndex = (row-1)*3 + col;
            errorMsg = sprintf('Error playing sample %d: %s', sampleIndex, ME.message);
            app.TextArea.Value = errorMsg;
        end
        end

        function updateWaveformDisplay(app, audioData, fs, titleStr)
        cla(app.UIAxes);

        [~, channels] = size(audioData);
        if channels > 1
            plotData = audioData(:, 1);
        else
            plotData = audioData;
        end
        
        t = (0:length(plotData)-1) / fs;
        
        plot(app.UIAxes, t, audioData, 'b');
        
        title(app.UIAxes, titleStr);
        xlabel(app.UIAxes, 'Time (s)');
        ylabel(app.UIAxes, 'Amplitude');
        grid(app.UIAxes, 'on');
        
        if ~isempty(t)
            xlim(app.UIAxes, [0, t(end)]);
        end
        
        drawnow;
        end

        function loadAudioPlugin(app, row, col)
            [file, path] = uigetfile({'*.wav;*.mp3;*.m4a;*.flac', 'Audio Files'});
            if isequal(file, 0)
                return; 
            end
            
            fullPath = fullfile(path, file);
            try
                [y, fs] = audioread(fullPath);
                app.SampleData_Cell{row, col} = y;
                app.Samplefs{row, col} = fs;
                sampleIndex = (row-1)*3 + col;
                btnName = ['Sample', num2str(sampleIndex), 'Button'];

                if isprop(app, btnName)
                    app.(btnName).Text = file;
                end
                
                app.TextArea.Value = sprintf('Loaded %s into Sample %d', file, (row-1)*3 + col);
            catch ME
                app.TextArea.Value = 'Error loading file.';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadAudioPlugin(app,1,1)
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            loadAudioPlugin(app,1,2)
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            loadAudioPlugin(app,1,3)
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            loadAudioPlugin(app,2,1)
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            loadAudioPlugin(app,2,2)
        end

        % Button pushed function: LoadButton_6
        function LoadButton_6Pushed(app, event)
            loadAudioPlugin(app,2,3)
        end

        % Button pushed function: LoadButton_7
        function LoadButton_7Pushed(app, event)
            loadAudioPlugin(app,3,1)
        end

        % Button pushed function: LoadButton_8
        function LoadButton_8Pushed(app, event)
            loadAudioPlugin(app,3,2)
        end

        % Button pushed function: LoadButton_9
        function LoadButton_9Pushed(app, event)
            loadAudioPlugin(app,3,3)
        end

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            playAudioSample(app, 1, 1);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            playAudioSample(app, 1, 2);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            playAudioSample(app, 1, 3);
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            playAudioSample(app, 2, 1);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            playAudioSample(app, 2, 2);
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            playAudioSample(app, 2, 3);
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            playAudioSample(app, 3, 1);
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            playAudioSample(app, 3, 2);
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            playAudioSample(app, 3, 3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Soundwave')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SampleSelectionPanel = uipanel(app.UIFigure);
            app.SampleSelectionPanel.Title = 'Sample Selection';
            app.Sample1Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample 1';
            app.LoadButton = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Sample2Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample 2';
            app.LoadButton_2 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.Sample3Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample 3';
            app.LoadButton_3 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.Sample4Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample 4';
            app.LoadButton_4 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.Sample5Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample 5';
            app.LoadButton_5 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.Sample6Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample 6';
            app.LoadButton_6 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_6Pushed, true);
            app.LoadButton_6.Text = 'Load';
            app.Sample7Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample 7';
            app.LoadButton_7 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_7Pushed, true);
            app.LoadButton_7.Text = 'Load';
            app.Sample8Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample 8';
            app.LoadButton_8 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_8Pushed, true);
            app.LoadButton_8.Text = 'Load';
            app.Sample9Button = uibutton(app.SampleSelectionPanel, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample 9';
            app.LoadButton_9 = uibutton(app.SampleSelectionPanel, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_9Pushed, true);
            app.LoadButton_9.Text = 'Load';
            app.TextArea = uitextarea(app.UIFigure);
            app.EffectsandSampleModificationPanel = uipanel(app.UIFigure);
            app.EffectsandSampleModificationPanel.Title = 'Effects and Sample Modification';
            app.SampleNoDropDownLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDownLabel.Text = 'Sample No';
            app.SampleNoDropDown = uidropdown(app.EffectsandSampleModificationPanel);
            app.SampleNoDropDown.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown.Value = 'Sample 1';
            app.SaveButton = uibutton(app.EffectsandSampleModificationPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.PlaybackSppedSlider = uislider(app.EffectsandSampleModificationPanel);
            app.PlaybackSppedSlider.Limits = [0.25 2];
            app.PlaybackSppedSlider.Value = 0.25;
            app.PlaybackSpeedLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackSpeedLabel.Text = 'Playback Speed';
            app.VolumeLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeLabel.Text = 'Volume';
            app.VolumeLevelKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.VolumeLevelKnobLabel.Text = 'Volume Level';
            app.VolumeLevelKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.Switch = uiswitch(app.EffectsandSampleModificationPanel, 'slider');
            app.Switch.Items = {'Backward', 'Forward'};
            app.Switch.Value = 'Backward';
            app.PlaybackDirectionLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.PlaybackDirectionLabel.Text = 'Playback Direction';
            app.DelayLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayLabel.Text = 'Delay';
            app.FeedbackLevelKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.FeedbackLevelKnobLabel.Text = 'Feedback Level';
            app.FeedbackLevelKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.FeedbackLevelKnob.Limits = [0 5];
            app.DelayTimeKnobLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.DelayTimeKnobLabel.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.EffectsandSampleModificationPanel, 'continuous');
            app.DelayTimeKnob.Limits = [0 2];
            app.EqualizerLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.EqualizerLabel.Text = 'Equalizer';
            app.HzSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.HzSliderLabel.Text = '63 Hz';
            app.HzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider.Limits = [-3 3];
            app.HzLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel.Text = '125 Hz';
            app.HzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzLabel_2 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_2.Text = '250 Hz';
            app.HzSlider_3 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzLabel_3 = uilabel(app.EffectsandSampleModificationPanel);
            app.HzLabel_3.Text = '500 Hz';
            app.HzSlider_4 = uislider(app.EffectsandSampleModificationPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.kHzSliderLabel = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSliderLabel.Text = '1k Hz';
            app.kHzSlider = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSlider.Limits = [-3 3];
            app.kHzSlider_2Label = uilabel(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2Label.Text = '2k Hz';
            app.kHzSlider_2 = uislider(app.EffectsandSampleModificationPanel);
            app.kHzSlider_2.Limits = [-3 3];
            app.ChoppingPanel = uipanel(app.UIFigure);
            app.ChoppingPanel.Title = 'Chopping';
            app.SaveButton_2 = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton_2.Text = 'Save';
            app.SampleNoDropDown_2Label = uilabel(app.ChoppingPanel);
            app.SampleNoDropDown_2Label.Text = 'Sample No';
            app.SampleNoDropDown_2 = uidropdown(app.ChoppingPanel);
            app.SampleNoDropDown_2.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown_2.Value = 'Sample 1';
            app.StartSliderLabel = uilabel(app.ChoppingPanel);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.ChoppingPanel);
            app.EndSliderLabel = uilabel(app.ChoppingPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.ChoppingPanel);
            app.SynthesizerPanel = uipanel(app.UIFigure);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes2 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes2, 'Soundwave')
            xlabel(app.UIAxes2, 'Times')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton_3 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_3.Text = 'Save';
            app.SampleNoDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SampleNoDropDown_3Label.Text = 'Sample No';
            app.SampleNoDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SampleNoDropDown_3.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleNoDropDown_3.Value = 'Sample 1';
            app.ButtonGroup = uibuttongroup(app.SynthesizerPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.SineWaveButton = uiradiobutton(app.ButtonGroup);
            app.SineWaveButton.Text = 'Sine Wave';
            app.SineWaveButton.Value = true;
            app.SquareWaveButton = uiradiobutton(app.ButtonGroup);
            app.SquareWaveButton.Text = 'Square Wave';
            app.SawtoothWaveButton = uiradiobutton(app.ButtonGroup);
            app.SawtoothWaveButton.Text = 'Sawtooth Wave';
            app.AMWaveButton = uiradiobutton(app.ButtonGroup);
            app.AMWaveButton.Text = 'AM Wave';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end