classdef app_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WindowSizeEditField            matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        DurationSecondsEditField       matlab.ui.control.NumericEditField
        DurationSecondsEditFieldLabel  matlab.ui.control.Label
        DelaySecondsEditField          matlab.ui.control.NumericEditField
        DelaySecondsEditFieldLabel     matlab.ui.control.Label
        SmoothSignalButton             matlab.ui.control.Button
        ResetTargetSignalButton        matlab.ui.control.Button
        IntegralTargetSignalButton_2   matlab.ui.control.Button
        CollectDataButton              matlab.ui.control.Button
        SetTargetSignalDropDown        matlab.ui.control.DropDown
        SetTargetSignalDropDownLabel   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    properties (Access = private)
        Analyzer
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Analyzer = SensorDataAnalyzer;
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            delay = app.DelaySecondsEditField.Value;
            duration = app.DurationSecondsEditField.Value;
            app.Analyzer.logSensorData(duration, delay); 

            signalName = app.SetTargetSignalDropDown.Value;
            app.Analyzer.setTargetSignal(signalName)
            app.Analyzer.plotTargetSignal(app.UIAxes, signalName);
        end

        % Button pushed function: SmoothSignalButton
        function SmoothSignalButtonPushed(app, event)
            windowSize = app.WindowSizeEditField.Value; 
            app.Analyzer.smooth(windowSize);
            signalName = app.SetTargetSignalDropDown.Value;
            app.Analyzer.plotTargetSignal(app.UIAxes, signalName);
        end

        % Value changed function: SetTargetSignalDropDown
        function SetTargetSignalDropDownValueChanged(app, event)
            signalName = app.SetTargetSignalDropDown.Value;
            app.Analyzer.setTargetSignal(signalName);
            app.Analyzer.plotTargetSignal(app.UIAxes, signalName);
        end

        % Button pushed function: IntegralTargetSignalButton_2
        function IntegralTargetSignalButton_2Pushed(app, event)
            app.Analyzer.integrate();
            signalName = app.SetTargetSignalDropDown.Value;
            app.Analyzer.plotTargetSignal(app.UIAxes, signalName);
        end

        % Button pushed function: ResetTargetSignalButton
        function ResetTargetSignalButtonPushed(app, event)
            app.Analyzer.resetTargetSignal();
            signalName = app.SetTargetSignalDropDown.Value;
            app.Analyzer.plotTargetSignal(app.UIAxes, signalName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Target Signal')
            xlabel(app.UIAxes, 'TimeStamp')
            ylabel(app.UIAxes, 'Signal Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 5 10 15 20];
            app.UIAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '截屏2025-11-16 19.51.30.png');
            app.SetTargetSignalDropDownLabel = uilabel(app.UIFigure);
            app.SetTargetSignalDropDownLabel.Text = 'Set Target Signal';
            app.SetTargetSignalDropDown = uidropdown(app.UIFigure);
            app.SetTargetSignalDropDown.Items = {'X-Accel', 'Y-Accel', 'Z-Accel'};
            app.SetTargetSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SetTargetSignalDropDownValueChanged, true);
            app.SetTargetSignalDropDown.Value = 'X-Accel';
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.IntegralTargetSignalButton_2 = uibutton(app.UIFigure, 'push');
            app.IntegralTargetSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @IntegralTargetSignalButton_2Pushed, true);
            app.IntegralTargetSignalButton_2.Text = 'Integral Target Signal';
            app.ResetTargetSignalButton = uibutton(app.UIFigure, 'push');
            app.ResetTargetSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTargetSignalButtonPushed, true);
            app.ResetTargetSignalButton.Text = 'Reset Target Signal';
            app.SmoothSignalButton = uibutton(app.UIFigure, 'push');
            app.SmoothSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothSignalButtonPushed, true);
            app.SmoothSignalButton.Text = 'Smooth Signal';
            app.DelaySecondsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaySecondsEditFieldLabel.Text = 'Delay (Seconds)';
            app.DelaySecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelaySecondsEditField.Value = 3;
            app.DurationSecondsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationSecondsEditFieldLabel.Text = 'Duration (Seconds)';
            app.DurationSecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationSecondsEditField.Value = 10;
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowSizeEditField.Value = 5;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end